classdef controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        kPValue                     matlab.ui.control.Label
        kPKnob                      matlab.ui.control.Knob
        kIValue                     matlab.ui.control.Label
        kIKnob                      matlab.ui.control.Knob
        kDValue                     matlab.ui.control.Label
        kDKnob                      matlab.ui.control.Knob
        ArduinoConnectionLampLabel  matlab.ui.control.Label
        statusLight                 matlab.ui.control.Lamp
        LEDControlPanel             matlab.ui.container.Panel
        led0                        matlab.ui.control.Button
        led1                        matlab.ui.control.Button
        led2                        matlab.ui.control.Button
        led3                        matlab.ui.control.Button
        led4                        matlab.ui.control.Button
        led5                        matlab.ui.control.Button
        led6                        matlab.ui.control.Button
        AutomaticallyChangeLabel    matlab.ui.control.Label
        ledSwitch                   matlab.ui.control.Switch
        PRMonitor                   matlab.ui.container.Panel
        PR0Value                    matlab.ui.control.Label
        PR0                         matlab.ui.control.Lamp
        PR1Value                    matlab.ui.control.Label
        PR1                         matlab.ui.control.Lamp
        PR2Value                    matlab.ui.control.Label
        PR2                         matlab.ui.control.Lamp
        PR3Value                    matlab.ui.control.Label
        PR3                         matlab.ui.control.Lamp
        PR4Value                    matlab.ui.control.Label
        PR4                         matlab.ui.control.Lamp
        titleLabel                  matlab.ui.control.Label
        SerialPortEditFieldLabel    matlab.ui.control.Label
        serialPort                  matlab.ui.control.EditField
        connectButton               matlab.ui.control.Button
        log                         matlab.ui.control.ListBox
        clearLogButton              matlab.ui.control.Button
        versionInfo                 matlab.ui.control.Label
        disconnectButton            matlab.ui.control.Button
        plotType                    matlab.ui.container.ButtonGroup
        ErrorButton                 matlab.ui.control.ToggleButton
        sumErrorButton              matlab.ui.control.ToggleButton
        dErrorButton                matlab.ui.control.ToggleButton
        PlotControlPanel            matlab.ui.container.Panel
        clearPlotButton             matlab.ui.control.Button
        PlotRollingSwitchLabel      matlab.ui.control.Label
        PlotRollingSwitch           matlab.ui.control.Switch
        ServoPositionGauge          matlab.ui.control.Gauge
        ServoPositionGaugeLabel     matlab.ui.control.Label
        ServoPositionLabel          matlab.ui.control.Label
        ErrorLabel                  matlab.ui.control.Label
        sumErrorLabel               matlab.ui.control.Label
        dErrorLabel                 matlab.ui.control.Label
        turnLabel                   matlab.ui.control.Label
        errorPlot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduino % Stores Arduino Serial
        state = 'idle' % Program State
        errorValues = [0]; % Stores error values for plotting
        sumErrorValues = [0]; % Stores sumError used for I
        dErrorValues = [0]; % Stores lastError used for D
        setLEDIndex = 0; % Description
        livePlotType = 'Error'; % Defines types of plot: 'Error', 'sumError' or 'dError'
        PRIndicators; % Handles to PR Indicator LEDs -- MUST BE INITIALIZED AFTER UI
        PRLabels; % Handles to PR Value Textboxes -- MUST BE INITIALIZED AFTER UI
    end
    
    methods (Access = public)
        
        
        function serialPort = findArduinoSerial(app)
            h1 = seriallist;
            pattern = '/dev/cu.usbmodem';
            hasDev = contains(h1,pattern);
            if any(hasDev)  % MACOS
                serialPort = h1(find(hasDev, 1));
                app.printLog(sprintf('Using port %s...', serialPort));
                return
            else  % Windows
                pattern = 'COM';
                hasCom = contains(h1,pattern);
                if any(hasCom)
                    serialPort = h1(find(hasCom, 1, 'last'));
                    app.printLog(sprintf('Using port %s...', serialPort));
                    return
                else % Cannot find port
                    app.printLog('Cannot find Arduino port. Is Arduino light on?');
                    serialPort = '';
                end
                return
            end
        end
        
        function printLog(app, str)
            if (~isempty(str))
                app.log.Items = [app.log.Items str];
                drawnow
                if(~isempty(app.log.Items))
                    app.log.scroll('bottom');
                end
            end
        end
        
        function result = connectArduino(app)
            result = false;
            if ~isempty(instrfind)
                fclose(instrfind);
            end
            app.arduino = serial(app.serialPort.Value,'baudrate',115200);
            app.printLog('Connecting to Arduino')
            try
                fopen(app.arduino);
            catch
                uialert(app.UIFigure, 'Cannot open Serial port. Please check that Arduino IDE is closed, or try unplugging and plugging in Arduino again.', 'Connection Error')
                app.serialPort.Value = '';
                return
            end
            pause(1);
            % Clear potentially buffered data
            while(app.arduino.BytesAvailable > 5)
                fread(app.arduino, 5);
            end
            % Start handshake
            fprintf(app.arduino, 'HELLO\n');
            pause(1)
            fgets(app.arduino);  % Get rid of previous content
            recv = fgets(app.arduino)
            if ~isempty(recv) && strcmp(recv(1:end-2), 'HELLO')
                app.printLog('Arduino Connected')
                result = true;
            else
                uialert(app.UIFigure, 'No response from Arduino. Check that you have flashed the provided Arduino Code, and Arduino IDE shows that the port detected is indeed going to Arduino Mega 2560.', 'Connection Error')
            end
        end
        
        function arduinoData = getData(app)
            arduinoData = {-1};
            writeStr = sprintf("GETDATA\n");
            try
                fprintf(app.arduino, writeStr);
            catch
                uialert(app.UIFigure, 'Error occurred writing to Arduino. Please reconnect', 'Connection Error')
                app.state = 'idle';
                return
            end
            htic0 = tic;
            while(toc(htic0) < 10) % Timeout after 10 seconds
                recv = deblank(fgets(app.arduino));
                if ~isempty(recv)
                    parsed = split(recv);  % last char is newline
                    if parsed(1) == "GETDATA"
                        arduinoData = parsed(2:end);
                        return
                    else
                        app.printLog(recv)
                    end
                end
            end
            uialert(app.UIFigure, 'Timeout receiving response. Did you upload the final Arduino code?', 'Connection Error');
        end
        
        function result = setLED(app, ledIndex)
            if ~isempty(ledIndex)
                writeStr = sprintf("SETLED %d\n", ledIndex);
                try
                    fprintf(app.arduino, writeStr);
                catch
                    uialert(app.UIFigure, 'Error occurred writting to Arduino. Please reconnect Arduino.', 'Connection Error')
                    app.state = 'idle';
                    return
                end
                htic0 = tic;
                while(toc(htic0) < 10) % Timeout after 10 seconds
                    recv = deblank(fgets(app.arduino));
                    if ~isempty(recv) && strcmp(recv, 'Done')
                        result = true;
                        return
                    else
                        app.printLog(recv)
                    end
                end
                errMsg = 'Arduino did not accept the command';
            else
                errMsg = 'Unexpected argument: index';
            end
            uialert(app.UIFigure, errMsg, 'Unexpected Error')
        end
        
        function result = setPID(app, kP, kI, kD)
            if ~isempty(kP) && ~isempty(kI) && ~isempty(kD)
                writeStr = sprintf("SETPID %f %f %f\n", kP, kI, kD);
                try
                    fprintf(app.arduino, writeStr);
                catch
                    uialert(app.UIFigure, 'Error occurred writting to Arduino. Please reconnect Arduino.', 'Connection Error')
                    app.state = 'idle';
                    return
                end
                htic0 = tic;
                while(toc(htic0) < 10) % Timeout after 10 seconds
                    recv = deblank(fgets(app.arduino));
                    if ~isempty(recv) && strcmp(recv, 'Done')
                        result = true;
                        return
                    else
                        app.printLog(recv)
                    end
                end
                errMsg = 'Arduino did not accept the command';
            else
                errMsg = 'Unexpected argument: index';
            end
            uialert(app.UIFigure, errMsg, 'Unexpected Error')
        end
        
        function setLEDHandle(app, index)
            if strcmp(app.state, 'connected')
                app.setLEDIndex = index;
                app.state = 'setLED';
            else
                app.printLog('Failed to set LED - Arduino busy or not connected');
            end
        end
        
        function updateStatusLight(app)
            switch app.state
                case 'idle'
                    app.statusLight.Color = [0.65 0.65 0.65];
                case 'connecting'
                    app.statusLight.Color = [0.93 0.69 0.13];
                case 'disconnecting'
                    app.statusLight.Color = [0.93 0.69 0.13];
                case 'connected'
                    app.statusLight.Color = [0 1 0];
                case 'setLED'
                    app.statusLight.Color = [0.93 0.69 0.13];
                case 'setPID'
                    app.statusLight.Color = [0.93 0.69 0.13];
                case 'closing'
                    app.statusLight.Color = [0.65 0.65 0.65];
            end
        end
        
        function parseData(app, data)
            kP = str2num(data{1});
            kI = str2num(data{2});
            kD = str2num(data{3});
            currError = str2num(data{4});
            sumError = str2num(data{5});
            lastError = str2num(data{6});
            turnRate = str2num(data{7});
            currentPos = str2num(data{8});
            PRData = cellfun(@str2num, data(9:end));
            dError = currError - lastError;
            app.errorValues = [app.errorValues currError];
            app.sumErrorValues = [app.sumErrorValues sumError];
            app.dErrorValues = [app.dErrorValues dError];
            
            % Update UI
            % PID and Error
            app.kPValue.Text = sprintf('kP=%.2f', kP);
            app.kIValue.Text = sprintf('kI=%.2f', kI);
            app.kDValue.Text = sprintf('kD=%.2f', kD);
            app.ErrorLabel.Text = sprintf('Error=%.2f', currError);
            app.sumErrorLabel.Text = sprintf('ΣError=%.2f', sumError);
            app.dErrorLabel.Text = sprintf('dError=%.2f', dError);
            app.turnLabel.Text = sprintf('turn=%.2f', turnRate);
            if(kP ~= app.kPKnob.Value)
                app.kPKnob.Value = kP;
            end
            if(kP ~= app.kIKnob.Value)
                app.kIKnob.Value = kI;
            end
            if(kP ~= app.kDKnob.Value)
                app.kDKnob.Value = kD;
            end
            
            % Servo Position
            app.ServoPositionGauge.Value = currentPos;
            app.ServoPositionLabel.Text = num2str(currentPos);
            % Photoresistor Readings
            for ii = 1:size(PRData,1)
                PRLight = PRData(ii);
                app.PRLabels(ii).Text = num2str(PRLight);
                PRLight = 1 - (min(max(0, PRLight), 100) / 100);
                app.PRIndicators(ii).Color = [PRLight PRLight PRLight];
            end
            
        end
        
        function updatePlot(app)
            switch(app.livePlotType)
                case 'Error'
                    plot(app.errorPlot, app.errorValues, 'LineWidth', 3)
                    app.errorPlot.Title.String = 'Error vs. Time';
                case 'ΣError'
                    plot(app.errorPlot, app.sumErrorValues, 'LineWidth', 3)
                    app.errorPlot.Title.String = 'Sum of Error vs Time';
                case 'dError'
                    plot(app.errorPlot, app.dErrorValues, 'LineWidth', 3)
                    app.errorPlot.Title.String = 'Error - LastError vs Time';
            end
            
            % Set XY Lim
            if(strcmp(app.PlotRollingSwitch.Value, 'On'))
                dataCounts = size(app.errorValues, 2);
                switch(app.livePlotType)
                    case 'Error'
                        maxLim = max(1, ceil(max(abs(app.errorValues(max(1, dataCounts - 50):dataCounts)))));
                    case 'ΣError'
                        maxLim = max(1, ceil(max(abs(app.sumErrorValues(max(1, dataCounts - 50):dataCounts)))));
                    case 'dError'
                        maxLim = max(1, ceil(max(abs(app.dErrorValues(max(1, dataCounts - 50):dataCounts)))));
                end
                app.errorPlot.XLim = [max(0, dataCounts - 50), dataCounts];
                app.errorPlot.YLim = [-maxLim, maxLim];
            else
                switch(app.livePlotType)
                    case 'Error'
                        maxLim = max(1, ceil(max(app.errorValues)));
                    case 'ΣError'
                        maxLim = max(1, ceil(max(app.sumErrorValues)));
                    case 'dError'
                        maxLim = max(1, ceil(max(app.dErrorValues)));
                end
                app.errorPlot.XLimMode = 'auto';
                app.errorPlot.YLim = [-maxLim, maxLim];
                
            end
        end
        
        function eventLoop(app)
            while 1
                app.updateStatusLight();
                switch app.state
                    case 'idle'
                        pause(0.1)
                    case 'connecting'
                        if isempty(app.serialPort.Value)
                            if(app.detectSerialPort())
                            else
                                app.printLog('Cannot detect Arduino Port. Please check Arduino or manually type in')
                                app.state = 'idle';
                                continue
                            end
                        end
                        if app.connectArduino()
                            app.state = 'connected';
                            app.disconnectButton.Enable = 'on';
                        else
                            app.state = 'idle';
                        end
                    case 'disconnecting'
                        fclose(instrfind);
                        app.serialPort.Value = '';
                        app.state = 'idle';
                        app.disconnectButton.Enable = 'off';
                    case 'connected'
                        data = app.getData();
                        if data{1} ~= -1
                            app.parseData(data);
                            app.updatePlot();
                        else
                            app.printLog('Warning: Failed to fetch data from Arduino');
                        end
                        pause(0.1)
                    case 'closing'
                        app.printLog('Disconnecting from Arduino ...');
                        return
                    case 'setLED'
                        app.setLED(app.setLEDIndex);
                        app.state = 'connected';
                    case 'setPID'
                        app.setPID(app.kPKnob.Value, app.kIKnob.Value, app.kDKnob.Value);
                        app.state = 'connected';
                    otherwise
                        uialert(app.UIFigure, 'The App encountered an unrecognized state. Please restart.', 'Unexpected Error');
                        pause(3)
                        return
                end
                drawnow % Update UI
            end
        end
        
        function success = detectSerialPort(app)
            success = false;
            result = app.findArduinoSerial();
            if(~isempty(result))
                app.serialPort.Value = result{1};
                success = true;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Finish leftover properties to be initialized after UI
            app.PRIndicators = [app.PR0 app.PR1 app.PR2 app.PR3 app.PR4]; % Handles to PR Indicator LEDs
            app.PRLabels = [app.PR0Value app.PR1Value app.PR2Value app.PR3Value app.PR4Value]; % Handles to PR Value Textboxes
            
            % Initialize
            app.detectSerialPort();
            % Start event loop
            app.eventLoop();
            
            % When event loop ends, app shuts down
            if ~isempty(instrfind)
                fclose(instrfind);
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.state = 'closing';
        end

        % Button pushed function: clearLogButton
        function clearLogButtonPushed(app, event)
            app.log.Items = {};
            drawnow
        end

        % Button pushed function: clearPlotButton
        function clearPlotButtonPushed(app, event)
            app.errorValues = [0];
            app.sumErrorValues = [0];
            app.dErrorValues = [0];
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            if strcmp(app.state, 'idle')
                app.state = 'connecting';
            else
                app.printLog('Arduino is connecting or already connected');
            end
        end

        % Button pushed function: disconnectButton
        function disconnectButtonPushed(app, event)
            app.state = 'disconnecting';
        end

        % Value changed function: kDKnob
        function kDKnobValueChanged(app, event)
            if strcmp(app.state, 'connected')
                app.state = 'setPID';
            else
                app.printLog('Failed to set PID - Arduino busy or not connected');
            end
        end

        % Value changed function: kIKnob
        function kIKnobValueChanged(app, event)
            if strcmp(app.state, 'connected')
                app.state = 'setPID';
            else
                app.printLog('Failed to set PID - Arduino busy or not connected');
            end
        end

        % Value changed function: kPKnob
        function kPKnobValueChanged(app, event)
            if strcmp(app.state, 'connected')
                app.state = 'setPID';
            else
                app.printLog('Failed to set PID - Arduino busy or not connected');
            end
        end

        % Button pushed function: led0
        function led0ButtonPushed(app, event)
            app.setLEDHandle(0);
        end

        % Button pushed function: led1
        function led1ButtonPushed(app, event)
            app.setLEDHandle(1);
        end

        % Button pushed function: led2
        function led2ButtonPushed(app, event)
            app.setLEDHandle(2);
        end

        % Button pushed function: led3
        function led3ButtonPushed(app, event)
            app.setLEDHandle(3);
        end

        % Button pushed function: led4
        function led4ButtonPushed(app, event)
            app.setLEDHandle(4);
        end

        % Button pushed function: led5
        function led5ButtonPushed(app, event)
            app.setLEDHandle(5);
        end

        % Button pushed function: led6
        function led6ButtonPushed(app, event)
            app.setLEDHandle(6);
        end

        % Value changed function: ledSwitch
        function ledSwitchValueChanged(app, event)
            value = app.ledSwitch.Value;
            if strcmp(value, 'Off')
                app.setLEDHandle(-1);
            else
                app.setLEDHandle(-2);
            end
        end

        % Selection changed function: plotType
        function plotTypeSelectionChanged(app, event)
            selectedButton = app.plotType.SelectedObject;
            app.livePlotType = selectedButton.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.kPValue = uilabel(app.UIFigure);
            app.kPValue.Text = 'kP';
            app.kPKnob = uiknob(app.UIFigure, 'continuous');
            app.kPKnob.Limits = [0 20];
            app.kPKnob.ValueChangedFcn = createCallbackFcn(app, @kPKnobValueChanged, true);
            app.kIValue = uilabel(app.UIFigure);
            app.kIValue.Text = 'kI';
            app.kIKnob = uiknob(app.UIFigure, 'continuous');
            app.kIKnob.Limits = [0 1];
            app.kIKnob.ValueChangedFcn = createCallbackFcn(app, @kIKnobValueChanged, true);
            app.kDValue = uilabel(app.UIFigure);
            app.kDValue.Text = 'kD';
            app.kDKnob = uiknob(app.UIFigure, 'continuous');
            app.kDKnob.Limits = [0 10];
            app.kDKnob.ValueChangedFcn = createCallbackFcn(app, @kDKnobValueChanged, true);
            app.ArduinoConnectionLampLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionLampLabel.Text = 'Arduino Connection';
            app.statusLight = uilamp(app.UIFigure);
            app.LEDControlPanel = uipanel(app.UIFigure);
            app.LEDControlPanel.Title = 'LED Control';
            app.led0 = uibutton(app.LEDControlPanel, 'push');
            app.led0.ButtonPushedFcn = createCallbackFcn(app, @led0ButtonPushed, true);
            app.led0.Text = '0';
            app.led1 = uibutton(app.LEDControlPanel, 'push');
            app.led1.ButtonPushedFcn = createCallbackFcn(app, @led1ButtonPushed, true);
            app.led1.Text = '1';
            app.led2 = uibutton(app.LEDControlPanel, 'push');
            app.led2.ButtonPushedFcn = createCallbackFcn(app, @led2ButtonPushed, true);
            app.led2.Text = '2';
            app.led3 = uibutton(app.LEDControlPanel, 'push');
            app.led3.ButtonPushedFcn = createCallbackFcn(app, @led3ButtonPushed, true);
            app.led3.Text = '3';
            app.led4 = uibutton(app.LEDControlPanel, 'push');
            app.led4.ButtonPushedFcn = createCallbackFcn(app, @led4ButtonPushed, true);
            app.led4.Text = '4';
            app.led5 = uibutton(app.LEDControlPanel, 'push');
            app.led5.ButtonPushedFcn = createCallbackFcn(app, @led5ButtonPushed, true);
            app.led5.Text = '5';
            app.led6 = uibutton(app.LEDControlPanel, 'push');
            app.led6.ButtonPushedFcn = createCallbackFcn(app, @led6ButtonPushed, true);
            app.led6.Text = '6';
            app.AutomaticallyChangeLabel = uilabel(app.LEDControlPanel);
            app.AutomaticallyChangeLabel.Text = 'Automatically Change';
            app.ledSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.ledSwitch.ValueChangedFcn = createCallbackFcn(app, @ledSwitchValueChanged, true);
            app.ledSwitch.Value = 'On';
            app.PRMonitor = uipanel(app.UIFigure);
            app.PRMonitor.Title = 'Photoresistor Readings';
            app.PR0Value = uilabel(app.PRMonitor);
            app.PR0Value.Text = '-';
            app.PR0 = uilamp(app.PRMonitor);
            app.PR1Value = uilabel(app.PRMonitor);
            app.PR1Value.Text = '-';
            app.PR1 = uilamp(app.PRMonitor);
            app.PR2Value = uilabel(app.PRMonitor);
            app.PR2Value.Text = '-';
            app.PR2 = uilamp(app.PRMonitor);
            app.PR3Value = uilabel(app.PRMonitor);
            app.PR3Value.Text = '-';
            app.PR3 = uilamp(app.PRMonitor);
            app.PR4Value = uilabel(app.PRMonitor);
            app.PR4Value.Text = '-';
            app.PR4 = uilamp(app.PRMonitor);
            app.titleLabel = uilabel(app.UIFigure);
            app.titleLabel.Text = 'ECE 5 Lab 4 Light Following Arm Control Panel';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.serialPort = uieditfield(app.UIFigure, 'text');
            app.connectButton = uibutton(app.UIFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'Connect';
            app.log = uilistbox(app.UIFigure);
            app.log.Items = {};
            app.log.Value = {};
            app.clearLogButton = uibutton(app.UIFigure, 'push');
            app.clearLogButton.ButtonPushedFcn = createCallbackFcn(app, @clearLogButtonPushed, true);
            app.clearLogButton.Text = 'Clear Log';
            app.versionInfo = uilabel(app.UIFigure);
            app.versionInfo.Text = 'Beta V1.3 20200527';
            app.disconnectButton = uibutton(app.UIFigure, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnectButton.Enable = 'off';
            app.disconnectButton.Text = 'Disconnect';
            app.plotType = uibuttongroup(app.UIFigure);
            app.plotType.SelectionChangedFcn = createCallbackFcn(app, @plotTypeSelectionChanged, true);
            app.plotType.Title = 'Plot';
            app.ErrorButton = uitogglebutton(app.plotType);
            app.ErrorButton.Text = 'Error';
            app.ErrorButton.Value = true;
            app.sumErrorButton = uitogglebutton(app.plotType);
            app.sumErrorButton.Text = 'ΣError';
            app.dErrorButton = uitogglebutton(app.plotType);
            app.dErrorButton.Text = 'dError';
            app.PlotControlPanel = uipanel(app.UIFigure);
            app.PlotControlPanel.Title = 'Plot Control';
            app.clearPlotButton = uibutton(app.PlotControlPanel, 'push');
            app.clearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @clearPlotButtonPushed, true);
            app.clearPlotButton.Text = 'Clear Plot';
            app.PlotRollingSwitchLabel = uilabel(app.PlotControlPanel);
            app.PlotRollingSwitchLabel.Text = 'Plot Rolling';
            app.PlotRollingSwitch = uiswitch(app.PlotControlPanel, 'slider');
            app.PlotRollingSwitch.Value = 'On';
            app.ServoPositionGauge = uigauge(app.UIFigure, 'circular');
            app.ServoPositionGauge.Limits = [0 180];
            app.ServoPositionGauge.MajorTicks = [0 180];
            app.ServoPositionGauge.ScaleDirection = 'counterclockwise';
            app.ServoPositionGaugeLabel = uilabel(app.UIFigure);
            app.ServoPositionGaugeLabel.Text = 'Servo Position';
            app.ServoPositionLabel = uilabel(app.UIFigure);
            app.ServoPositionLabel.Text = '-';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error=';
            app.sumErrorLabel = uilabel(app.UIFigure);
            app.sumErrorLabel.Text = 'ΣError=';
            app.dErrorLabel = uilabel(app.UIFigure);
            app.dErrorLabel.Text = 'dError=';
            app.turnLabel = uilabel(app.UIFigure);
            app.turnLabel.Text = 'turn=';
            app.errorPlot = uiaxes(app.UIFigure);
            title(app.errorPlot, 'Error Plot')
            xlabel(app.errorPlot, 'Time')
            app.errorPlot.XTick = [];
            app.errorPlot.XTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end